classdef AutmatedKineticModeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutmatedKineticModelingUIFigure  matlab.ui.Figure
        btnFolderSelect              matlab.ui.control.Button
        InputFolderLabel             matlab.ui.control.Label
        chkFolder                    matlab.ui.control.CheckBox
        txtPath                      matlab.ui.control.EditField
        chkMovCor                    matlab.ui.control.CheckBox
        MovementCorrectionLabel      matlab.ui.control.Label
        chkACOrig                    matlab.ui.control.CheckBox
        SetACOriginLabel             matlab.ui.control.Label
        chkDicom                     matlab.ui.control.CheckBox
        DICOMImportLabel             matlab.ui.control.Label
        chkACOrig_2                  matlab.ui.control.CheckBox
        SpatialNormalizationLabel    matlab.ui.control.Label
        chkACOrig_3                  matlab.ui.control.CheckBox
        KineticModelingLabel         matlab.ui.control.Label
        chkACOrig_4                  matlab.ui.control.CheckBox
        StatisticalComparisonLabel   matlab.ui.control.Label
        NormalDatabaseDropDownLabel  matlab.ui.control.Label
        NormalDatabaseDropDown       matlab.ui.control.DropDown
        ProtocolTextAreaLabel        matlab.ui.control.Label
        ProtocolTextArea             matlab.ui.control.TextArea
        Button                       matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.txtPath.Value = pwd;
        end

        % Button pushed function: btnFolderSelect
        function btnFolderSelectButtonPushed(app, event)
            app.txtPath.Value = uigetdir;
            app.chkFolder.Value = true;
            
        end

        % Value changed function: txtPath
        function txtPathValueChanged(app, event)
            value = app.txtPath.Value;
            app.chkFolder.Value = false;
        end

        % Value changed function: NormalDatabaseDropDown
        function NormalDatabaseDropDownValueChanged(app, event)
            value = app.NormalDatabaseDropDown.Value;
            app.ProtocolTextArea.Value = strcat('Normal DB set: ', value);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            addpath('TestNormalizationPerformance/');
            myMessageBox('Test');
            rmpath('TestNormalizationPerformance/');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutmatedKineticModelingUIFigure = uifigure;
            app.AutmatedKineticModelingUIFigure.Name = 'Autmated Kinetic Modeling';
            app.btnFolderSelect = uibutton(app.AutmatedKineticModelingUIFigure, 'push');
            app.btnFolderSelect.ButtonPushedFcn = createCallbackFcn(app, @btnFolderSelectButtonPushed, true);
            app.btnFolderSelect.Text = 'Select';
            app.InputFolderLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.InputFolderLabel.Text = 'Input Folder';
            app.chkFolder = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkFolder.Enable = 'off';
            app.chkFolder.Text = '';
            app.txtPath = uieditfield(app.AutmatedKineticModelingUIFigure, 'text');
            app.txtPath.ValueChangedFcn = createCallbackFcn(app, @txtPathValueChanged, true);
            app.chkMovCor = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkMovCor.Enable = 'off';
            app.chkMovCor.Text = '';
            app.MovementCorrectionLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.MovementCorrectionLabel.Text = 'Movement Correction';
            app.chkACOrig = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkACOrig.Enable = 'off';
            app.chkACOrig.Text = '';
            app.SetACOriginLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.SetACOriginLabel.Text = 'Set AC Origin';
            app.chkDicom = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkDicom.Enable = 'off';
            app.chkDicom.Text = '';
            app.DICOMImportLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.DICOMImportLabel.Text = 'DICOM Import';
            app.chkACOrig_2 = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkACOrig_2.Enable = 'off';
            app.chkACOrig_2.Text = '';
            app.SpatialNormalizationLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.SpatialNormalizationLabel.Text = 'Spatial Normalization';
            app.chkACOrig_3 = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkACOrig_3.Enable = 'off';
            app.chkACOrig_3.Text = '';
            app.KineticModelingLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.KineticModelingLabel.Text = 'Kinetic Modeling';
            app.chkACOrig_4 = uicheckbox(app.AutmatedKineticModelingUIFigure);
            app.chkACOrig_4.Enable = 'off';
            app.chkACOrig_4.Text = '';
            app.StatisticalComparisonLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.StatisticalComparisonLabel.Text = 'Statistical Comparison';
            app.NormalDatabaseDropDownLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.NormalDatabaseDropDownLabel.Text = 'Normal Database';
            app.NormalDatabaseDropDown = uidropdown(app.AutmatedKineticModelingUIFigure);
            app.NormalDatabaseDropDown.Items = {'Life/Piramal 10 HC PI2620', 'select other'};
            app.NormalDatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalDatabaseDropDownValueChanged, true);
            app.NormalDatabaseDropDown.Value = 'Life/Piramal 10 HC PI2620';
            app.ProtocolTextAreaLabel = uilabel(app.AutmatedKineticModelingUIFigure);
            app.ProtocolTextAreaLabel.Text = 'Protocol';
            app.ProtocolTextArea = uitextarea(app.AutmatedKineticModelingUIFigure);
            app.ProtocolTextArea.Value = {'Startup!'};
            app.Button = uibutton(app.AutmatedKineticModelingUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = AutmatedKineticModeling

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutmatedKineticModelingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutmatedKineticModelingUIFigure)
        end
    end
end